classdef Markerstable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MarkersUIFigure  matlab.ui.Figure
        UITable          matlab.ui.control.Table
    end

    
    properties (Access = private)
        Caller % Description
    end
    
    methods (Access = public)
        function updateTable(app, freqVector, powerVector)

            % Create an index column
            indexColumn = (1:length(freqVector))';

            % Combine the data into a cell array (optional) or a numeric matrix
            tableData = [indexColumn, freqVector, powerVector];

            % Clear and update the table
            app.UITable.Data = [];
            app.UITable.Data = tableData;
        end
        function externalClose(app)
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.Caller = caller;
        end

        % Close request function: MarkersUIFigure
        function MarkersUIFigureCloseRequest(app, event)
            app.Caller.getRxTabObj.setTableClosed();
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MarkersUIFigure = uifigure('Visible', 'off');
            app.MarkersUIFigure.Name = 'Markers';
            app.MarkersUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.MarkersUIFigure.CloseRequestFcn = createCallbackFcn(app, @MarkersUIFigureCloseRequest, true);
            app.UITable = uitable(app.MarkersUIFigure);
            app.UITable.ColumnName = {'Marker'; 'Frequency [Hz]'; 'Power [dBm]'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false];
            app.UITable.ColumnEditable = [false false false];
            % Show the figure after all components are created
            app.MarkersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Markerstable(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MarkersUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MarkersUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MarkersUIFigure)
        end
    end
end